classdef OrbitalSimulationApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        InitialstateLabel              matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        StateVectorPanel               matlab.ui.container.Panel
        VzkmsEditField                 matlab.ui.control.NumericEditField
        vzkmsEditFieldLabel            matlab.ui.control.Label
        VykmsEditField                 matlab.ui.control.NumericEditField
        vykmsEditFieldLabel            matlab.ui.control.Label
        VxkmsEditField                 matlab.ui.control.NumericEditField
        vxkmsEditFieldLabel            matlab.ui.control.Label
        ZkmEditField                   matlab.ui.control.NumericEditField
        zkmEditFieldLabel              matlab.ui.control.Label
        YkmEditField                   matlab.ui.control.NumericEditField
        ykmEditFieldLabel              matlab.ui.control.Label
        XkmEditField                   matlab.ui.control.NumericEditField
        xkmEditFieldLabel              matlab.ui.control.Label
        InitialStateDropDown           matlab.ui.control.DropDown
        InitialstateDropDownLabel      matlab.ui.control.Label
        OrbitalElementsPanel           matlab.ui.container.Panel
        TrueanomalydegEditField        matlab.ui.control.NumericEditField
        TrueanomalydegEditFieldLabel   matlab.ui.control.Label
        ArgofperigeedegEditField       matlab.ui.control.NumericEditField
        ArgofperigeedegEditFieldLabel  matlab.ui.control.Label
        RAANdegEditField               matlab.ui.control.NumericEditField
        RAANdegEditFieldLabel          matlab.ui.control.Label
        LTANEditField                  matlab.ui.control.NumericEditField
        LTANhEditField_2Label          matlab.ui.control.Label
        InclinationdegEditField        matlab.ui.control.EditField
        InclinationdegEditFieldLabel   matlab.ui.control.Label
        EccentricityEditField          matlab.ui.control.EditField
        EccentricityLabel              matlab.ui.control.Label
        SemimajoraxiskmEditField       matlab.ui.control.EditField
        SemimajoraxiskmEditFieldLabel  matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        SummaryTab                     matlab.ui.container.Tab
        SummaryTextArea                matlab.ui.control.TextArea
        SimulationSummaryLabel         matlab.ui.control.Label
        OrbitTable                     matlab.ui.control.Table
        OrbitalelementsLabel           matlab.ui.control.Label
        PlotsTab                       matlab.ui.container.Tab
        PlotStatisticsTextArea         matlab.ui.control.TextArea
        PlotStatisticsLabel            matlab.ui.control.Label
        GraphDropDown                  matlab.ui.control.DropDown
        ParameterDropDownLabel         matlab.ui.control.Label
        OrbitAxes                      matlab.ui.control.UIAxes
        GroundStationsTab              matlab.ui.container.Tab
        SessionsTable                  matlab.ui.control.Table
        StationSummaryTable            matlab.ui.control.Table
        StationDropDown                matlab.ui.control.DropDown
        StatiomLabel                   matlab.ui.control.Label
        VisibilityAxes                 matlab.ui.control.UIAxes
        DurationdaysEditField_2        matlab.ui.control.NumericEditField
        DurationdaysEditField_2Label   matlab.ui.control.Label
        ResultsLabel                   matlab.ui.control.Label
        SimulationparametersLabel      matlab.ui.control.Label
        SPACECRAFTORBITALSIMULATIONLabel  matlab.ui.control.Label
        RunSimulationButton            matlab.ui.control.Button
        CrEditField                    matlab.ui.control.EditField
        CrLabel                        matlab.ui.control.Label
        Aream2EditField                matlab.ui.control.EditField
        Aream2EditFieldLabel           matlab.ui.control.Label
        MasskgEditField                matlab.ui.control.EditField
        MasskgEditFieldLabel           matlab.ui.control.Label
        SpacecraftLabel                matlab.ui.control.Label
        SeparationTimeEditField        matlab.ui.control.EditField
        SeparationTimeLabel            matlab.ui.control.Label
        LaunchTimeEditField            matlab.ui.control.EditField
        LaunchTimeLabel                matlab.ui.control.Label
        ScenarioLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        Results
    end
    
    methods (Access = private)

     function updateGroundStation(app)

    %% Check simulation results

    if isempty(app.Results)
        return
    end

    Results = app.Results;


    %% Selected station

    selectedStation = string(app.StationDropDown.Value);

    stationData = Results.Visibility.stationData;

    stationIndex = [];

    for k = 1:numel(stationData)

        if string(stationData(k).name) == selectedStation
            stationIndex = k;
            break
        end

    end


    %% Check station

    if isempty(stationIndex)

        uialert( ...
            app.UIFigure, ...
            'Selected ground station was not found.', ...
            'Station error');

        return

    end


    %% Get station data

    Station = stationData(stationIndex);

    Data = Results.Data;


    %% Find station in input data

    stationInput = [];

    for k = 1:numel(Data.Station)

        if string(Data.Station(k).name) == selectedStation
            stationInput = Data.Station(k);
            break
        end

    end


    %% Number of sessions

    numberOfSessions = numel(Station.sessions);


    %% First and last session

    if numberOfSessions > 0

        firstSession = Station.sessions(1);
        lastSession  = Station.sessions(end);

        firstSessionTime = firstSession.startTime;
        lastSessionTime  = lastSession.endTime;

    else

        firstSessionTime = NaT;
        lastSessionTime  = NaT;

    end


    %% =====================================================
    %  Station summary
    %  =====================================================

    if numberOfSessions > 0

        firstSessionText = ...
            datestr(firstSessionTime, ...
            'dd-mmm-yyyy HH:MM:SS');

        lastSessionText = ...
            datestr(lastSessionTime, ...
            'dd-mmm-yyyy HH:MM:SS');

    else

        firstSessionText = 'No sessions';
        lastSessionText  = 'No sessions';

    end


    app.StationSummaryTable.Data = {

        'Station', ...
        char(selectedStation)

        'Latitude [deg]', ...
        sprintf('%.4f', stationInput.lat)

        'Longitude [deg]', ...
        sprintf('%.4f', stationInput.lon)

        'Altitude [km]', ...
        sprintf('%.3f', stationInput.alt)

        'Min elevation [deg]', ...
        sprintf('%.1f', Station.minElevation)

        'Number of sessions', ...
        sprintf('%d', numberOfSessions)

        'First session', ...
        firstSessionText

        'Last session', ...
        lastSessionText

    };


    %% =====================================================
    %  Sessions table
    %  =====================================================

    if numberOfSessions == 0

        app.SessionsTable.Data = {};

    else

        sessionData = cell(numberOfSessions,4);

        for k = 1:numberOfSessions

            session = Station.sessions(k);

            sessionData{k,1} = ...
                datestr( ...
                session.startTime, ...
                'dd-mmm-yyyy HH:MM:SS');

            sessionData{k,2} = ...
                datestr( ...
                session.endTime, ...
                'dd-mmm-yyyy HH:MM:SS');

            sessionData{k,3} = ...
                sprintf( ...
                '%.4f', ...
                session.duration / 60);

            sessionData{k,4} = ...
                sprintf( ...
                '%.4f', ...
                session.maxElevation);

        end

        app.SessionsTable.Data = sessionData;

    end


    %% =====================================================
    %  Visibility plot
    %  =====================================================

    cla(app.VisibilityAxes);


    if isempty(Station.time)

        title( ...
            app.VisibilityAxes, ...
            char(selectedStation));

        return

    end


    %% Convert time to hours

    if isnumeric(Station.time)

        time = Station.time / 3600;

    elseif isduration(Station.time)

        time = hours(Station.time);

    elseif isdatetime(Station.time)

        time = hours(Station.time - Station.time(1));

    else

        error( ...
            'Unsupported time format in Station.time.');

    end


    %% Elevation

    elevation = Station.elevation;


    %% Plot elevation

    plot( ...
        app.VisibilityAxes, ...
        time, ...
        elevation, ...
        'LineWidth', 1.0);

    hold(app.VisibilityAxes, 'on');


    %% Minimum elevation line

    yline( ...
        app.VisibilityAxes, ...
        Station.minElevation, ...
        '--', ...
        'Min elevation', ...
        'LineWidth', 1.0);


    %% Formatting

    grid( ...
        app.VisibilityAxes, ...
        'on');

    xlabel( ...
        app.VisibilityAxes, ...
        'Time [h]');

    ylabel( ...
        app.VisibilityAxes, ...
        'Elevation [deg]');

    title( ...
        app.VisibilityAxes, ...
        char(selectedStation));

    xlim( ...
        app.VisibilityAxes, ...
        [time(1) time(end)]);

    ylim( ...
        app.VisibilityAxes, ...
        [-90 90]);

    hold( ...
        app.VisibilityAxes, ...
        'off');

end
     function plotSelectedGraph(app)

    % If simulation results are not available
    if isempty(app.Results)
        return
    end

    Results = app.Results;

    % -------------------------------------------------
    % Select parameter
    % -------------------------------------------------

    switch app.GraphDropDown.Value

        % =================================================
        % TRAJECTORY PARAMETERS
        % =================================================

        case 'Altitude'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.height;

            yLabel = 'Altitude [km]';
            titleText = 'Spacecraft altitude';


        case 'Semi-major axis'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.a;

            yLabel = 'Semi-major axis [km]';
            titleText = 'Semi-major axis';


        case 'Perigee altitude'

            time = Results.Orbit.time / 3600;

            a = Results.Orbit.a;
            e = Results.Orbit.e;
            Re = Results.Constants.Re;

            y = a .* (1 - e) - Re;

            yLabel = 'Perigee altitude [km]';
            titleText = 'Perigee altitude';


        case 'Apogee altitude'

            time = Results.Orbit.time / 3600;

            a = Results.Orbit.a;
            e = Results.Orbit.e;
            Re = Results.Constants.Re;

            y = a .* (1 + e) - Re;

            yLabel = 'Apogee altitude [km]';
            titleText = 'Apogee altitude';


        case 'Orbital period'

            time = Results.Orbit.time / 3600;

            a = Results.Orbit.a;
            mu = Results.Constants.mu;

            period = 2*pi*sqrt(a.^3 ./ mu);

            y = period / 60;

            yLabel = 'Orbital period [min]';
            titleText = 'Orbital period';


        case 'Eccentricity'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.e;

            yLabel = 'Eccentricity';
            titleText = 'Eccentricity';


        case 'Inclination'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.i;

            yLabel = 'Inclination [deg]';
            titleText = 'Inclination';


        case 'RAAN'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.raan;

            yLabel = 'RAAN [deg]';
            titleText = 'Right ascension of ascending node';


        case 'Argument of perigee'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.argPerigee;

            yLabel = 'Argument of perigee [deg]';
            titleText = 'Argument of perigee';


        case 'True anomaly'

            time = Results.Orbit.time / 3600;
            y = Results.Orbit.trueAnomaly;

            yLabel = 'True anomaly [deg]';
            titleText = 'True anomaly';


        case 'Unwrapped argument of latitude'

            time = Results.Orbit.time / 3600;

            u = Results.Orbit.argPerigee + ...
                Results.Orbit.trueAnomaly;

            y = unwrap(deg2rad(u));
            y = rad2deg(y);

            yLabel = 'Unwrapped argument of latitude [deg]';
            titleText = 'Unwrapped argument of latitude';


        % =================================================
        % ASCENDING NODE PARAMETERS
        % =================================================

        case 'Ascending-node altitude'

            time = Results.AscendingNode.time / 3600;
            y = Results.AscendingNode.height;

            yLabel = 'Altitude at ascending node [km]';
            titleText = 'Ascending-node altitude';


        case 'Ascending-node semi-major axis'

            time = Results.AscendingNode.time / 3600;
            y = Results.AscendingNode.a;

            yLabel = 'Semi-major axis at ascending node [km]';
            titleText = 'Ascending-node semi-major axis';


        case 'Ascending-node eccentricity'

            time = Results.AscendingNode.time / 3600;
            y = Results.AscendingNode.e;

            yLabel = 'Eccentricity at ascending node';
            titleText = 'Ascending-node eccentricity';


        case 'Ascending-node inclination'

            time = Results.AscendingNode.time / 3600;
            y = Results.AscendingNode.i;

            yLabel = 'Inclination at ascending node [deg]';
            titleText = 'Ascending-node inclination';


        case 'Ascending-node RAAN'

            time = Results.AscendingNode.time / 3600;
            y = Results.AscendingNode.raan;

            yLabel = 'RAAN at ascending node [deg]';
            titleText = 'Ascending-node RAAN';


        case 'Ascending-node LTAN'

            time = Results.AscendingNode.time / 3600;
            y = Results.AscendingNode.ltan;

            yLabel = 'LTAN [h]';
            titleText = 'Local time of ascending node';


        otherwise

            return

    end


    % -------------------------------------------------
    % Plot
    % -------------------------------------------------

    cla(app.OrbitAxes);

    plot( ...
        app.OrbitAxes, ...
        time, ...
        y, ...
        'LineWidth', 1.2);

    grid(app.OrbitAxes, 'on');

    xlabel(app.OrbitAxes, 'Time [h]');
    ylabel(app.OrbitAxes, yLabel);
    title(app.OrbitAxes, titleText);


    % -------------------------------------------------
    % Plot statistics
    % -------------------------------------------------

    initialValue = y(1);
    finalValue = y(end);

    minimumValue = min(y);
    maximumValue = max(y);

    changeValue = finalValue - initialValue;

    app.PlotStatisticsTextArea.Value = {
        sprintf('Initial: %.6f', initialValue)
        sprintf('Final: %.6f', finalValue)
        sprintf('Change: %.6f', changeValue)
        sprintf('Minimum: %.6f', minimumValue)
        sprintf('Maximum: %.6f', maximumValue)
    };

     end  
        
     function text = formatDateTime(dt)

    if isempty(dt) || ismissing(dt)

        text = '—';

    else

        text = char( ...
            datestr(dt, 'dd-mmm-yyyy HH:MM:SS'));

    end

end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
    %% Add project folders to MATLAB path

    projectRoot = fileparts(mfilename('fullpath'));
    addpath(genpath(projectRoot));


    %% Configure orbit results table

    app.OrbitTable.ColumnName = {
    'First AN'
    'Last AN'
            };
    app.OrbitTable.RowName = {
        'Semi-major axis [km]'
        'Eccentricity'
        'Inclination [deg]'
        'RAAN [deg]'
        'Argument of perigee [deg]'
        'True anomaly [deg]'
    };


    %% Configure summary

    app.SummaryTextArea.Editable = 'off';


   %% Configure ground station dropdown
        
        Data = inputData();
        
        stationNames = cell(1, numel(Data.Station));
        
        for k = 1:numel(Data.Station)
            stationNames{k} = char(Data.Station(k).name);
        end
        
        app.StationDropDown.Items = stationNames;
        
        if ~isempty(stationNames)
            app.StationDropDown.Value = stationNames{1};
        end

    %% Configure Ground Stations

    app.StationSummaryTable.ColumnName = ...
        {'Parameter', 'Value'};

    app.StationSummaryTable.RowName = {};


    app.SessionsTable.ColumnName = {
        'Start'
        'End'
        'Duration [min]'
        'Max elevation [deg]'
    };

    app.SessionsTable.RowName = {};

    % Initial state
    app.InitialStateDropDown.Value = 'Orbital elements';
    app.OrbitalElementsPanel.Visible = 'on';
    app.StateVectorPanel.Visible = 'off';

        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)


    %% Create input data

    Data = inputData();


    %% Read scenario parameters

    Data.Scenario.launchTime = datetime( ...
        app.LaunchTimeEditField.Value, ...
        'InputFormat', 'dd-MMM-yyyy HH:mm:ss');

    Data.Scenario.separationTime = datetime( ...
        app.SeparationTimeEditField.Value, ...
        'InputFormat', 'dd-MMM-yyyy HH:mm:ss');

    Data.Scenario.duration = ...
        days(app.DurationdaysEditField_2.Value);

    Data.Scenario.stopTime = ...
        Data.Scenario.separationTime + ...
        Data.Scenario.duration;


    %% Spacecraft parameters

    Data.Spacecraft.mass = ...
        str2double(string(app.MasskgEditField.Value));

    Data.Spacecraft.area = ...
        str2double(string(app.Aream2EditField.Value));

    Data.Spacecraft.Cr = ...
        str2double(string(app.CrEditField.Value));


    %% Initial state

    switch app.InitialStateDropDown.Value

        case 'Orbital elements'

            Data.InitialState.mode = "OrbitalElements";

            Data.Orbit.a = ...
                str2double(string( ...
                app.SemimajoraxiskmEditField.Value));

            Data.Orbit.e = ...
                str2double(string( ...
                app.EccentricityEditField.Value));

            Data.Orbit.i = ...
                str2double(string( ...
                app.InclinationdegEditField.Value));

            Data.Orbit.raan = ...
                str2double(string( ...
                app.RAANdegEditField.Value));

            Data.Orbit.argPerigee = ...
                str2double(string( ...
                app.ArgofperigeedegEditField.Value));

            Data.Orbit.trueAnomaly = ...
                str2double(string( ...
                app.TrueanomalydegEditField.Value));


        case 'State vector'

            Data.InitialState.mode = "StateVector";

            Data.InitialState.position = [
                str2double(string( ...
                app.XkmEditField.Value))

                str2double(string( ...
                app.YkmEditField.Value))

                str2double(string( ...
                app.ZkmEditField.Value))
            ];

            Data.InitialState.velocity = [
                str2double(string( ...
                app.VxkmsEditField.Value))

                str2double(string( ...
                app.VykmsEditField.Value))

                str2double(string( ...
                app.VzkmsEditField.Value))
            ];

         
            Data.InitialState.epoch = ...
                Data.Scenario.separationTime;

            Data.InitialState.frame = "ECI";


        otherwise

            error('Unknown initial state type.');

    end


    %% Run simulation

    app.RunSimulationButton.Enable = 'off';
    drawnow;


    %% Progress dialog

    d = uiprogressdlg( ...
        app.UIFigure, ...
        'Title', 'Orbital simulation', ...
        'Message', 'Running simulation...', ...
        'Indeterminate', 'on');


    try

        %% Validate input

        validateInputData(Data);


        %% Run simulation

        Results = runSimulation(Data);

        app.Results = Results;

        S = Results.Summary;


        %% Close progress dialog

        if isvalid(d)
            close(d);
        end


        %% Update ground station results

        updateGroundStation(app);


        %% Display orbital elements

        % Compare the first and last ascending nodes.
        
        initial = ...
            Results.Summary.initialAscendingNodeElements;
        
        final = ...
            Results.Summary.finalAscendingNodeElements;
        
        app.OrbitTable.Data = {
        
            sprintf('%.3f', initial(1)), ...
            sprintf('%.3f', final(1))
        
            sprintf('%.6f', initial(2)), ...
            sprintf('%.6f', final(2))
        
            sprintf('%.4f', rad2deg(initial(3))), ...
            sprintf('%.4f', rad2deg(final(3)))
        
            sprintf('%.4f', rad2deg(initial(4))), ...
            sprintf('%.4f', rad2deg(final(4)))
        
            sprintf('%.4f', rad2deg(initial(5))), ...
            sprintf('%.4f', rad2deg(final(5)))
        
            sprintf('%.4f', rad2deg(initial(6))), ...
            sprintf('%.4f', rad2deg(final(6)))
        };


        %% First communication session

        firstSession = ...
            Results.Visibility.firstSession;

        if ~isempty(firstSession)

            stationName = ...
                Results.Visibility.firstStationName;

            firstStart = ...
                firstSession.startTime;

            firstEnd = ...
                firstSession.endTime;

            firstMaxElevationTime = ...
                firstSession.maxElevationTime;

            hasFirstSession = true;

        else

            stationName = "No visibility";

            firstStart = NaT;
            firstEnd = NaT;
            firstMaxElevationTime = NaT;

            hasFirstSession = false;

        end


       %% Simulation summary

        summaryText = {

        'SIMULATION SUMMARY'
        '=============================='
    
        ''
        'SCENARIO'
        '------------------------------'
    
        sprintf('Start time: %s', ...
            datestr( ...
            Data.Scenario.separationTime, ...
            'dd-mmm-yyyy HH:MM:SS'))
    
        sprintf('Duration: %.1f days', ...
            Data.Scenario.duration / days(1))
    
    
        ''
        'SPACECRAFT'
        '------------------------------'
    
        sprintf('Mass: %.1f kg', ...
            Data.Spacecraft.mass)
    
        sprintf('Reference area: %.3f m^2', ...
            Data.Spacecraft.area)
    
    
        ''
        'INITIAL STATE'
        '------------------------------'
    
        sprintf('Source: %s', ...
            S.initialStateSource)
    
        sprintf('Semi-major axis: %.3f km', ...
            S.initialElements(1))
    
        sprintf('Eccentricity: %.6f', ...
            S.initialElements(2))
    
        sprintf('Inclination: %.4f deg', ...
            rad2deg(S.initialElements(3)))
    
    
        ''
        'ORBIT DECAY'
        '------------------------------'
    
        'Comparison: first ascending node -> last ascending node'
    
        sprintf('Semi-major axis change: %+.3f km', ...
            S.semiMajorAxisChange)
    
        sprintf('Perigee altitude change: %+.3f km', ...
            S.perigeeAltitudeChange)
    
        sprintf('Apogee altitude change: %+.3f km', ...
            S.apogeeAltitudeChange)
    
    
        ''
        'ORBITAL CHARACTERISTICS'
        '------------------------------'
    
        sprintf('Revolutions completed: %d', ...
            S.completedRevolutions)
    
        sprintf('Total revolutions: %.3f', ...
            S.totalRevolutions)
    
        sprintf('Mean orbital period: %.3f min', ...
            S.meanPeriod / 60)
    
        sprintf('Keplerian period: %.3f min', ...
            S.keplerPeriod / 60)
    
        sprintf('Period difference: %+.3f s', ...
            S.periodDifference)
    
        sprintf('Relative difference: %+.4f %%', ...
            S.periodDifferencePercent)
    
    
        ''
        'PROPAGATION'
        '------------------------------'
    
        sprintf('Last ascending node: %s', ...
            datestr( ...
            S.lastAscendingNode, ...
            'dd-mmm-yyyy HH:MM:SS.FFF'))
    
        sprintf('Integration points: %d', ...
            S.integrationPoints)
    
    };

        %% Add first communication session

        if hasFirstSession

            summaryText = [
                summaryText
                {
                ''
                'FIRST COMMUNICATION SESSION'
                '------------------------------'

                sprintf('Station: %s', ...
                    stationName)

                sprintf('Start UTC: %s', ...
                    datestr( ...
                    firstStart, ...
                    'dd-mmm-yyyy HH:MM:SS.FFF'))

                sprintf('End UTC:   %s', ...
                    datestr( ...
                    firstEnd, ...
                    'dd-mmm-yyyy HH:MM:SS.FFF'))

                sprintf('Duration: %.3f s', ...
                    firstSession.duration)

                sprintf('Max elevation: %.3f deg', ...
                    firstSession.maxElevation)

                sprintf('Max elevation UTC: %s', ...
                    datestr( ...
                    firstMaxElevationTime, ...
                    'dd-mmm-yyyy HH:MM:SS.FFF'))

                ''
                }
            ];

        else

            summaryText = [
                summaryText
                {
                ''
                'FIRST COMMUNICATION SESSION'
                '------------------------------'
                'No communication sessions found.'
                ''
                }
            ];

        end


        %% Display summary

        app.SummaryTextArea.Value = summaryText;


        %% Plot selected graph

        plotSelectedGraph(app);


        %% Enable button

        app.RunSimulationButton.Enable = 'on';


    catch ME

        %% Close progress dialog

        if isvalid(d)
            close(d);
        end


        %% Enable button

        app.RunSimulationButton.Enable = 'on';


        %% Print error to Command Window

        fprintf('\nERROR:\n');
        fprintf('%s\n', ME.message);

        for k = 1:length(ME.stack)

            fprintf('\nFile: %s\n', ...
                ME.stack(k).file);

            fprintf('Line: %d\n', ...
                ME.stack(k).line);

            fprintf('Function: %s\n', ...
                ME.stack(k).name);

        end


        %% Display error

        uialert( ...
            app.UIFigure, ...
            ME.message, ...
            'Simulation error');

    end

        end

        % Value changed function: SummaryTextArea
        function SummaryTextAreaValueChanged(app, event)
            value = app.SummaryTextArea.Value;
            
        end

        % Value changed function: GraphDropDown
        function GraphDropDownValueChanged(app, event)
            plotSelectedGraph(app);
            
        end

        % Value changed function: StationDropDown
        function StationDropDownValueChanged(app, event)

            updateGroundStation(app);

        end

        % Value changed function: InitialStateDropDown
        function InitialStateDropDownValueChanged(app, event)
            
        switch app.InitialStateDropDown.Value

        case 'Orbital elements'

            app.OrbitalElementsPanel.Visible = 'on';
            app.StateVectorPanel.Visible = 'off';

        case 'State vector'

            app.OrbitalElementsPanel.Visible = 'off';
            app.StateVectorPanel.Visible = 'on';

        end
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)

    %% Load default data

    Data = inputData();


    %% Scenario

    app.LaunchTimeEditField.Value = ...
        char(string(Data.Scenario.launchTime, ...
        'dd-MMM-yyyy HH:mm:ss'));

    app.SeparationTimeEditField.Value = ...
        char(string(Data.Scenario.separationTime, ...
        'dd-MMM-yyyy HH:mm:ss'));

    app.DurationdaysEditField_2.Value = ...
        days(Data.Scenario.duration);


    %% Spacecraft

    app.MasskgEditField.Value = ...
        num2str(Data.Spacecraft.mass);

    app.Aream2EditField.Value = ...
        num2str(Data.Spacecraft.area);

    app.CrEditField.Value = ...
        num2str(Data.Spacecraft.Cr);


    %% Initial state

    app.InitialStateDropDown.Value = ...
        'Orbital elements';

    app.OrbitalElementsPanel.Visible = 'on';
    app.StateVectorPanel.Visible = 'off';


    %% Orbital elements

    app.SemimajoraxiskmEditField.Value = ...
        num2str(Data.Orbit.a);

    app.EccentricityEditField.Value = ...
        num2str(Data.Orbit.e);

    app.InclinationdegEditField.Value = ...
        num2str(Data.Orbit.i);

    app.RAANdegEditField.Value = ...
        Data.Orbit.raan;

    app.ArgofperigeedegEditField.Value = ...
        Data.Orbit.argPerigee;

    app.TrueanomalydegEditField.Value = ...
        Data.Orbit.trueAnomaly;

    app.LTANEditField.Value = ...
        hours(Data.Orbit.LTAN);


    %% State vector

    app.XkmEditField.Value = 0;
    app.YkmEditField.Value = 0;
    app.ZkmEditField.Value = 0;

    app.VxkmsEditField.Value = 0;
    app.VykmsEditField.Value = 0;
    app.VzkmsEditField.Value = 0;


    %% Clear results

    app.OrbitTable.Data = {};

    app.SummaryTextArea.Value = '';

    app.PlotStatisticsTextArea.Value = '';

    cla(app.OrbitAxes, 'reset');


    %% Clear stored results

    app.Results = [];

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ScenarioLabel = uilabel(app.UIFigure);
            app.ScenarioLabel.Text = 'Scenario';
            app.LaunchTimeLabel = uilabel(app.UIFigure);
            app.LaunchTimeLabel.Text = 'Launch Time';
            app.LaunchTimeEditField = uieditfield(app.UIFigure, 'text');
            app.LaunchTimeEditField.Value = '01-May-2026 04:30:00';
            app.SeparationTimeLabel = uilabel(app.UIFigure);
            app.SeparationTimeLabel.Text = 'Separation Time';
            app.SeparationTimeEditField = uieditfield(app.UIFigure, 'text');
            app.SeparationTimeEditField.Value = '01-May-2026 04:38:11';
            app.SpacecraftLabel = uilabel(app.UIFigure);
            app.SpacecraftLabel.Text = 'Spacecraft';
            app.MasskgEditFieldLabel = uilabel(app.UIFigure);
            app.MasskgEditFieldLabel.Text = 'Mass [kg]';
            app.MasskgEditField = uieditfield(app.UIFigure, 'text');
            app.MasskgEditField.Value = '500';
            app.Aream2EditFieldLabel = uilabel(app.UIFigure);
            app.Aream2EditFieldLabel.Text = 'Area [m^2]';
            app.Aream2EditField = uieditfield(app.UIFigure, 'text');
            app.Aream2EditField.Value = '2.5';
            app.CrLabel = uilabel(app.UIFigure);
            app.CrLabel.Text = 'Cr';
            app.CrEditField = uieditfield(app.UIFigure, 'text');
            app.CrEditField.Value = '1.3';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.SPACECRAFTORBITALSIMULATIONLabel = uilabel(app.UIFigure);
            app.SPACECRAFTORBITALSIMULATIONLabel.Text = '  SPACECRAFT ORBITAL SIMULATION ';
            app.SimulationparametersLabel = uilabel(app.UIFigure);
            app.SimulationparametersLabel.Text = 'Simulation parameters  ';
            app.ResultsLabel = uilabel(app.UIFigure);
            app.ResultsLabel.Text = 'Results ';
            app.DurationdaysEditField_2Label = uilabel(app.UIFigure);
            app.DurationdaysEditField_2Label.Text = 'Duration [days]';
            app.DurationdaysEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.DurationdaysEditField_2.Value = 7;
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SummaryTab = uitab(app.TabGroup);
            app.SummaryTab.Title = 'Summary';
            app.OrbitalelementsLabel = uilabel(app.SummaryTab);
            app.OrbitalelementsLabel.Text = 'Orbital elements ';
            app.OrbitTable = uitable(app.SummaryTab);
            app.OrbitTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.OrbitTable.RowName = {};
            app.SimulationSummaryLabel = uilabel(app.SummaryTab);
            app.SimulationSummaryLabel.Text = 'Simulation Summary';
            app.SummaryTextArea = uitextarea(app.SummaryTab);
            app.SummaryTextArea.ValueChangedFcn = createCallbackFcn(app, @SummaryTextAreaValueChanged, true);
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.OrbitAxes = uiaxes(app.PlotsTab);
            title(app.OrbitAxes, 'OrbitAxes')
            xlabel(app.OrbitAxes, 'X')
            ylabel(app.OrbitAxes, 'Y')
            zlabel(app.OrbitAxes, 'Z')
            app.ParameterDropDownLabel = uilabel(app.PlotsTab);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.GraphDropDown = uidropdown(app.PlotsTab);
            app.GraphDropDown.Items = {'Altitude', 'Semi-major axis', 'Perigee altitude', 'Apogee altitude', 'Orbital period', 'Eccentricity', 'Inclination', 'RAAN', 'Argument of perigee', 'True anomaly', 'Unwrapped true anomaly', 'Unwrapped argument of latitude', 'Ascending-node altitude', 'Ascending-node semi-major axis', 'Ascending-node eccentricity', 'Ascending-node inclination', 'Ascending-node RAAN', 'Ascending-node LTAN'};
            app.GraphDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphDropDownValueChanged, true);
            app.GraphDropDown.Value = 'Altitude';
            app.PlotStatisticsLabel = uilabel(app.PlotsTab);
            app.PlotStatisticsLabel.Text = 'Plot Statistics';
            app.PlotStatisticsTextArea = uitextarea(app.PlotsTab);
            app.PlotStatisticsTextArea.Editable = 'off';
            app.GroundStationsTab = uitab(app.TabGroup);
            app.GroundStationsTab.Title = 'Station';
            app.VisibilityAxes = uiaxes(app.GroundStationsTab);
            title(app.VisibilityAxes, 'Title')
            xlabel(app.VisibilityAxes, 'X')
            ylabel(app.VisibilityAxes, 'Y')
            zlabel(app.VisibilityAxes, 'Z')
            app.StatiomLabel = uilabel(app.GroundStationsTab);
            app.StatiomLabel.Text = 'Station';
            app.StationDropDown = uidropdown(app.GroundStationsTab);
            app.StationDropDown.Items = {'Station 1', 'Station 2'};
            app.StationDropDown.ValueChangedFcn = createCallbackFcn(app, @StationDropDownValueChanged, true);
            app.StationDropDown.Value = 'Station 1';
            app.StationSummaryTable = uitable(app.GroundStationsTab);
            app.StationSummaryTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.StationSummaryTable.RowName = {};
            app.SessionsTable = uitable(app.GroundStationsTab);
            app.SessionsTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.SessionsTable.RowName = {};
            app.OrbitalElementsPanel = uipanel(app.UIFigure);
            app.OrbitalElementsPanel.Title = 'Orbital elements';
            app.SemimajoraxiskmEditFieldLabel = uilabel(app.OrbitalElementsPanel);
            app.SemimajoraxiskmEditFieldLabel.Text = 'Semi-major axis [km]';
            app.SemimajoraxiskmEditField = uieditfield(app.OrbitalElementsPanel, 'text');
            app.SemimajoraxiskmEditField.Value = '6872.526';
            app.EccentricityLabel = uilabel(app.OrbitalElementsPanel);
            app.EccentricityLabel.Text = 'Eccentricity';
            app.EccentricityEditField = uieditfield(app.OrbitalElementsPanel, 'text');
            app.EccentricityEditField.Value = '0.00135';
            app.InclinationdegEditFieldLabel = uilabel(app.OrbitalElementsPanel);
            app.InclinationdegEditFieldLabel.Text = 'Inclination [deg]';
            app.InclinationdegEditField = uieditfield(app.OrbitalElementsPanel, 'text');
            app.InclinationdegEditField.Value = '97.455';
            app.LTANhEditField_2Label = uilabel(app.OrbitalElementsPanel);
            app.LTANhEditField_2Label.Text = 'LTAN [h]';
            app.LTANEditField = uieditfield(app.OrbitalElementsPanel, 'numeric');
            app.LTANEditField.Value = 10;
            app.RAANdegEditFieldLabel = uilabel(app.OrbitalElementsPanel);
            app.RAANdegEditFieldLabel.Text = 'RAAN [deg]';
            app.RAANdegEditField = uieditfield(app.OrbitalElementsPanel, 'numeric');
            app.ArgofperigeedegEditFieldLabel = uilabel(app.OrbitalElementsPanel);
            app.ArgofperigeedegEditFieldLabel.Text = 'Arg of perigee [deg]';
            app.ArgofperigeedegEditField = uieditfield(app.OrbitalElementsPanel, 'numeric');
            app.TrueanomalydegEditFieldLabel = uilabel(app.OrbitalElementsPanel);
            app.TrueanomalydegEditFieldLabel.Text = 'True anomaly [deg]';
            app.TrueanomalydegEditField = uieditfield(app.OrbitalElementsPanel, 'numeric');
            app.InitialstateDropDownLabel = uilabel(app.UIFigure);
            app.InitialstateDropDownLabel.Text = 'Initial state';
            app.InitialStateDropDown = uidropdown(app.UIFigure);
            app.InitialStateDropDown.Items = {'Orbital elements', 'State vector'};
            app.InitialStateDropDown.ValueChangedFcn = createCallbackFcn(app, @InitialStateDropDownValueChanged, true);
            app.InitialStateDropDown.Value = 'Orbital elements';
            app.StateVectorPanel = uipanel(app.UIFigure);
            app.StateVectorPanel.Title = ' State vector';
            app.xkmEditFieldLabel = uilabel(app.StateVectorPanel);
            app.xkmEditFieldLabel.Text = 'x [km]';
            app.XkmEditField = uieditfield(app.StateVectorPanel, 'numeric');
            app.XkmEditField.ValueDisplayFormat = '%.15g';
            app.ykmEditFieldLabel = uilabel(app.StateVectorPanel);
            app.ykmEditFieldLabel.Text = 'y [km]';
            app.YkmEditField = uieditfield(app.StateVectorPanel, 'numeric');
            app.YkmEditField.ValueDisplayFormat = '%.15g';
            app.zkmEditFieldLabel = uilabel(app.StateVectorPanel);
            app.zkmEditFieldLabel.Text = 'z [km]';
            app.ZkmEditField = uieditfield(app.StateVectorPanel, 'numeric');
            app.ZkmEditField.ValueDisplayFormat = '%.15g';
            app.vxkmsEditFieldLabel = uilabel(app.StateVectorPanel);
            app.vxkmsEditFieldLabel.Text = 'vx [km/s]';
            app.VxkmsEditField = uieditfield(app.StateVectorPanel, 'numeric');
            app.VxkmsEditField.ValueDisplayFormat = '%.15g';
            app.vykmsEditFieldLabel = uilabel(app.StateVectorPanel);
            app.vykmsEditFieldLabel.Text = 'vy [km/s]';
            app.VykmsEditField = uieditfield(app.StateVectorPanel, 'numeric');
            app.VykmsEditField.ValueDisplayFormat = '%.15g';
            app.vzkmsEditFieldLabel = uilabel(app.StateVectorPanel);
            app.vzkmsEditFieldLabel.Text = 'vz [km/s]';
            app.VzkmsEditField = uieditfield(app.StateVectorPanel, 'numeric');
            app.VzkmsEditField.ValueDisplayFormat = '%.15g';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.InitialstateLabel = uilabel(app.UIFigure);
            app.InitialstateLabel.Text = 'Initial state';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrbitalSimulationApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end